classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SMOREUIFigure               matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        SMORETab                    matlab.ui.container.Tab
        runButton                   matlab.ui.control.Button
        browseButton_2              matlab.ui.control.Button
        browseButton                matlab.ui.control.Button
        inputfileTextArea           matlab.ui.control.TextArea
        inputfileTextAreaLabel      matlab.ui.control.Label
        outputfolderTextArea        matlab.ui.control.TextArea
        outputfolderTextAreaLabel   matlab.ui.control.Label
        SettingsTab                 matlab.ui.container.Tab
        AdvancedPanel               matlab.ui.container.Panel
        nRefIterEditField           matlab.ui.control.NumericEditField
        nRefIterEditFieldLabel      matlab.ui.control.Label
        nRefEditField               matlab.ui.control.NumericEditField
        nRefEditFieldLabel          matlab.ui.control.Label
        nEvalEditField              matlab.ui.control.NumericEditField
        nEvalEditFieldLabel         matlab.ui.control.Label
        isDiffEnrichCheckBox        matlab.ui.control.CheckBox
        isEnrichCheckBox            matlab.ui.control.CheckBox
        patienceEditField           matlab.ui.control.NumericEditField
        patienceEditFieldLabel      matlab.ui.control.Label
        thresholdEditField          matlab.ui.control.NumericEditField
        thresholdEditFieldLabel     matlab.ui.control.Label
        SMOREPanel                  matlab.ui.container.Panel
        fixedTypesEditField         matlab.ui.control.EditField
        fixedTypesEditFieldLabel    matlab.ui.control.Label
        nMotifEditField             matlab.ui.control.NumericEditField
        nMotifEditFieldLabel        matlab.ui.control.Label
        nTrainEditField             matlab.ui.control.NumericEditField
        nTrainEditField_2Label      matlab.ui.control.Label
        nScoreEditField             matlab.ui.control.NumericEditField
        nScoreEditFieldLabel        matlab.ui.control.Label
        motiflengthEditField        matlab.ui.control.NumericEditField
        motiflengthEditFieldLabel   matlab.ui.control.Label
        URPENPanel                  matlab.ui.container.Panel
        samplingfreqEditField       matlab.ui.control.NumericEditField
        samplingfreqEditFieldLabel  matlab.ui.control.Label
        ShufflingPanel              matlab.ui.container.Panel
        shufflemodeDropDown         matlab.ui.control.DropDown
        shufflemodeDropDownLabel    matlab.ui.control.Label
        GraphSettingsPanel          matlab.ui.container.Panel
        gModeDropDown               matlab.ui.control.DropDown
        gModeDropDownLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        KNN=5 % Description
        rEps=0;
        neighDepth=5;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    
        end

        % Button pushed function: browseButton
        function browseButtonPushed(app, event)
            lastfolder='';
            [file, folder] = uigetfile({'*.csv'},' Select input', lastfolder);
            if file ~= 0
                setappdata(app.browseButton, 'lastfolder', strcat(folder, file));
                app.inputfileTextArea.Value = strcat(folder, file);
            end
        end

        % Button pushed function: browseButton_2
        function browseButton_2Pushed(app, event)
             lastfolder='';
            folder= uigetdir(lastfolder);
            
            if folder ~= 0
                setappdata(app.browseButton_2, 'lastfolder', folder);
                app.outputfolderTextArea.Value = folder;
            end           
        end

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            app.runstatusTextArea.Value='SMORE started\n';
            app.runstatusTextArea.Value=...
                smore(input=app.inputfileTextArea.Value{1},...
                    output=app.outputfolderTextArea.Value{1},...
                    fixedTypes=str2num(app.fixedTypesEditField.Value),...
                    alphabet=[],...
                     NEVAL=app.nEvalEditField.Value,...
                     NREF=app.nRefEditField.Value,...
                     WMotif=app.motiflengthEditField.Value,...
                     nRefIter=app.nRefIterEditField.Value,...
                     threshold=app.thresholdEditField.Value,...
                     patience=app.patienceEditField.Value,...
                     nmotifs=app.nMotifEditField.Value,...
                     isErzHOut=true,...
                     scIterMax=app.nScoreEditField.Value,...
                     gTrainNum=app.nTrainEditField.Value,...
                     diffMotif=app.isDiffEnrichCheckBox.Value,...
                     shuffleMode=app.shufflemodeDropDown.Value,...
                     isEnrich=app.isEnrichCheckBox.Value,...
                     samplingFreq=app.samplingfreqEditField.Value,...
                     gMode=app.gModeDropDown.Value,...
                     numNeighs=app.KNN,...
                     rEps=app.rEps,...
                     neighDepth=app.neighDepth,...
                     isOKSingleNC=true);
        end

        % Value changed function: gModeDropDown
        function gModeDropDownValueChanged(app, event)
            value = app.gModeDropDown.Value;
            fieldsize = [1 40];                
            opts.Interpreter = 'tex';
            if strcmpi(value, 'knn')
                prompt = {'Enter a value of K'};
                dlgtitle = 'number of neighbors';
                definput = {'5'};
                answer = inputdlg(prompt,dlgtitle,fieldsize,definput,opts);
                app.numNeighsEditField.Value=str2double(answer{1});
                app.KNN=str2double(answer{1});

            elseif  strcmpi(value, 'epsilon')
                prompt = {'Enter a value of epsilon'};
                dlgtitle = 'neighbor radius';
                definput = {'0'};
                answer = inputdlg(prompt,dlgtitle,fieldsize,definput,opts);
                app.rEpsilonEditField.Value=str2double(answer{1});
                app.rEps=str2double(answer{1});
            end
        end

        % Drop down opening function: gModeDropDown
        function gModeDropDownOpening(app, event)

        end

        % Value changed function: shufflemodeDropDown
        function shufflemodeDropDownValueChanged(app, event)
            fieldsize = [1 40];                
            opts.Interpreter = 'tex';
            value = app.shufflemodeDropDown.Value;
             if strcmpi(value, 'kernel')
                prompt = {'Enter a value of K'};
                dlgtitle = 'number of neighbors';
                definput = {'5'};
                answer = inputdlg(prompt,dlgtitle,fieldsize,definput,opts);
                app.neighDepth=str2double(answer{1});
              end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SMOREUIFigure = uifigure('Visible', 'off');
            app.SMOREUIFigure.Name = 'SMORE';
            app.TabGroup = uitabgroup(app.SMOREUIFigure);
            app.SMORETab = uitab(app.TabGroup);
            app.SMORETab.Title = 'SMORE';
            app.outputfolderTextAreaLabel = uilabel(app.SMORETab);
            app.outputfolderTextAreaLabel.Text = 'output folder';
            app.outputfolderTextArea = uitextarea(app.SMORETab);
            app.outputfolderTextArea.Value = {'C:\Users\zainsamadi\Documents\GitHub\SMORE\output\'};
            app.inputfileTextAreaLabel = uilabel(app.SMORETab);
            app.inputfileTextAreaLabel.Text = 'input file';
            app.inputfileTextArea = uitextarea(app.SMORETab);
            app.inputfileTextArea.Value = {'C:\Users\zainsamadi\Documents\GitHub\SMORE\data\sampleDataEb.csv'};
            app.browseButton = uibutton(app.SMORETab, 'push');
            app.browseButton.ButtonPushedFcn = createCallbackFcn(app, @browseButtonPushed, true);
            app.browseButton.Text = 'browse';
            app.browseButton_2 = uibutton(app.SMORETab, 'push');
            app.browseButton_2.ButtonPushedFcn = createCallbackFcn(app, @browseButton_2Pushed, true);
            app.browseButton_2.Text = 'browse';
            app.runButton = uibutton(app.SMORETab, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'run';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GraphSettingsPanel = uipanel(app.SettingsTab);
            app.GraphSettingsPanel.Title = 'Graph Settings';
            app.gModeDropDownLabel = uilabel(app.GraphSettingsPanel);
            app.gModeDropDownLabel.Text = 'gMode';
            app.gModeDropDown = uidropdown(app.GraphSettingsPanel);
            app.gModeDropDown.Items = {'delauney', 'knn', 'epsilon'};
            app.gModeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @gModeDropDownOpening, true);
            app.gModeDropDown.ValueChangedFcn = createCallbackFcn(app, @gModeDropDownValueChanged, true);
            app.gModeDropDown.Value = 'delauney';
            app.ShufflingPanel = uipanel(app.SettingsTab);
            app.ShufflingPanel.Title = 'Shuffling';
            app.shufflemodeDropDownLabel = uilabel(app.ShufflingPanel);
            app.shufflemodeDropDownLabel.Text = 'shuffle mode';
            app.shufflemodeDropDown = uidropdown(app.ShufflingPanel);
            app.shufflemodeDropDown.Items = {'shuffle', 'kernel'};
            app.shufflemodeDropDown.ValueChangedFcn = createCallbackFcn(app, @shufflemodeDropDownValueChanged, true);
            app.shufflemodeDropDown.Value = 'shuffle';
            app.URPENPanel = uipanel(app.SettingsTab);
            app.URPENPanel.Title = 'URPEN';
            app.samplingfreqEditFieldLabel = uilabel(app.URPENPanel);
            app.samplingfreqEditFieldLabel.Text = 'sampling freq';
            app.samplingfreqEditField = uieditfield(app.URPENPanel, 'numeric');
            app.samplingfreqEditField.Value = 0.5;
            app.SMOREPanel = uipanel(app.SettingsTab);
            app.SMOREPanel.Title = 'SMORE';
            app.motiflengthEditFieldLabel = uilabel(app.SMOREPanel);
            app.motiflengthEditFieldLabel.Text = 'motif length';
            app.motiflengthEditField = uieditfield(app.SMOREPanel, 'numeric');
            app.motiflengthEditField.Value = 4;
            app.nScoreEditFieldLabel = uilabel(app.SMOREPanel);
            app.nScoreEditFieldLabel.Text = 'nScore';
            app.nScoreEditField = uieditfield(app.SMOREPanel, 'numeric');
            app.nScoreEditField.Value = 5;
            app.nTrainEditField_2Label = uilabel(app.SMOREPanel);
            app.nTrainEditField_2Label.Text = 'nTrain';
            app.nTrainEditField = uieditfield(app.SMOREPanel, 'numeric');
            app.nTrainEditField.Value = 50;
            app.nMotifEditFieldLabel = uilabel(app.SMOREPanel);
            app.nMotifEditFieldLabel.Text = 'nMotif';
            app.nMotifEditField = uieditfield(app.SMOREPanel, 'numeric');
            app.nMotifEditField.Value = 5;
            app.fixedTypesEditFieldLabel = uilabel(app.SMOREPanel);
            app.fixedTypesEditFieldLabel.Text = 'fixed Types';
            app.fixedTypesEditField = uieditfield(app.SMOREPanel, 'text');
            app.fixedTypesEditField.Value = '0';
            app.AdvancedPanel = uipanel(app.SettingsTab);
            app.AdvancedPanel.Title = 'Advanced';
            app.thresholdEditFieldLabel = uilabel(app.AdvancedPanel);
            app.thresholdEditFieldLabel.Text = 'threshold';
            app.thresholdEditField = uieditfield(app.AdvancedPanel, 'numeric');
            app.thresholdEditField.Value = 0.05;
            app.patienceEditFieldLabel = uilabel(app.AdvancedPanel);
            app.patienceEditFieldLabel.Text = 'patience';
            app.patienceEditField = uieditfield(app.AdvancedPanel, 'numeric');
            app.patienceEditField.Value = 3;
            app.isEnrichCheckBox = uicheckbox(app.AdvancedPanel);
            app.isEnrichCheckBox.Text = 'isEnrich';
            app.isEnrichCheckBox.Value = true;
            app.isDiffEnrichCheckBox = uicheckbox(app.AdvancedPanel);
            app.isDiffEnrichCheckBox.Text = 'isDiffEnrich';
            app.nEvalEditFieldLabel = uilabel(app.AdvancedPanel);
            app.nEvalEditFieldLabel.Text = 'nEval';
            app.nEvalEditField = uieditfield(app.AdvancedPanel, 'numeric');
            app.nEvalEditField.Value = 25;
            app.nRefEditFieldLabel = uilabel(app.AdvancedPanel);
            app.nRefEditFieldLabel.Text = 'nRef';
            app.nRefEditField = uieditfield(app.AdvancedPanel, 'numeric');
            app.nRefEditField.Value = 4;
            app.nRefIterEditFieldLabel = uilabel(app.AdvancedPanel);
            app.nRefIterEditFieldLabel.Text = 'nRefIter';
            app.nRefIterEditField = uieditfield(app.AdvancedPanel, 'numeric');
            app.nRefIterEditField.Value = 20;
            % Show the figure after all components are created
            app.SMOREUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SMOREUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SMOREUIFigure)
        end
    end
end